classdef SegmentationROI3D < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        zproj_ax                     matlab.ui.control.UIAxes
        xproj_ax                     matlab.ui.control.UIAxes
        yproj_ax                     matlab.ui.control.UIAxes
        mask_3d                      matlab.ui.control.UIAxes
        TraceEstimate                matlab.ui.control.UIAxes
        MasknumberEditFieldLabel     matlab.ui.control.Label
        MasknumberEditField          matlab.ui.control.NumericEditField
        NextmaskButton               matlab.ui.control.Button
        PreviousmaskButton           matlab.ui.control.Button
        keepmaskCheckBox             matlab.ui.control.CheckBox
        SaveselectionandcloseButton  matlab.ui.control.Button
    end

    
    properties (Access = public)
        segmentation_data; % segmentation data for masks
        savepath; %savepath for the segmentation data
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, seg_data_path, segmentation_data)
            app.SaveselectionandcloseButton.BackgroundColor = 'r';
            app.SaveselectionandcloseButton.Text = 'Loading masks...';
            
            app.savepath = seg_data_path;
%             temp = load(seg_data_path,'-mat','segmentation_data');
            app.segmentation_data = segmentation_data;
            clear temp;
            
            app.SaveselectionandcloseButton.BackgroundColor = [0.96, 0.96, 0.96];
            app.SaveselectionandcloseButton.Text = 'Save selection and close';
            
            Nroi = numel(app.segmentation_data);
            
            app.MasknumberEditField.Limits = [1,Nroi];
            
            MasknumberEditFieldValueChanged(app);
        end

        % Button pushed function: NextmaskButton
        function NextmaskButtonPushed(app, event)
            mask_idx = app.MasknumberEditField.Value;
            if mask_idx + 1 <= app.MasknumberEditField.Limits(2)
                app.MasknumberEditField.Value = mask_idx + 1;
                MasknumberEditFieldValueChanged(app);
            end
        end

        % Button pushed function: PreviousmaskButton
        function PreviousmaskButtonPushed(app, event)
            mask_idx = app.MasknumberEditField.Value;
            if mask_idx - 1 >= app.MasknumberEditField.Limits(1)
                app.MasknumberEditField.Value = mask_idx - 1;
                MasknumberEditFieldValueChanged(app);
            end
        end

        % Callback function: MasknumberEditField, zproj_ax
        function MasknumberEditFieldValueChanged(app, event)
            mask_idx = app.MasknumberEditField.Value;
            
            app.keepmaskCheckBox.Value = app.segmentation_data(mask_idx).keep;
            
            zproj_im = app.segmentation_data(mask_idx).zproj;
            xproj_im = app.segmentation_data(mask_idx).xproj';
            yproj_im = app.segmentation_data(mask_idx).yproj;
            
            mask_3d_im = app.segmentation_data(mask_idx).mask_3d;
            
            trace = app.segmentation_data(mask_idx).mean_trace_hp;
            if size(mask_3d_im,3) == 1
                mask_3d_im(:,:,2) = false;
            end
 
            v = isosurface(mask_3d_im);
            cla(app.mask_3d);
            view(app.mask_3d,3);
            app.mask_3d.ZLim = [0,size(mask_3d_im,3)];
            patch(app.mask_3d,v,'FaceColor','red','EdgeColor','none');
            camlight(app.mask_3d, "headlight");
            lighting(app.mask_3d, "gouraud");  
            
            imagesc(app.zproj_ax,zproj_im);
            axis(app.zproj_ax,'image');
            
            imagesc(app.xproj_ax,xproj_im);
            axis(app.xproj_ax,'image');
            
            imagesc(app.yproj_ax,yproj_im);
            axis(app.yproj_ax,'image');
            
            plot(app.TraceEstimate,trace);
        end

        % Value changed function: keepmaskCheckBox
        function keepmaskCheckBoxValueChanged(app, event)
            mask_idx = app.MasknumberEditField.Value;
            app.segmentation_data(mask_idx).keep = app.keepmaskCheckBox.Value;
        end

        % Button pushed function: SaveselectionandcloseButton
        function SaveselectionandcloseButtonPushed(app, event)
            app.SaveselectionandcloseButton.BackgroundColor = 'r';
            app.SaveselectionandcloseButton.Text = 'Saving masks...';
            pause(2);

            seg_data = app.segmentation_data;
            save(app.savepath,'seg_data','-v7.3');
            close(app.UIFigure);
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            
            switch key
                case 'rightarrow'
                    NextmaskButtonPushed(app,event);
                case 'leftarrow'
                    PreviousmaskButtonPushed(app,event);
                case 'k'
                    app.keepmaskCheckBox.Value = ~app.keepmaskCheckBox.Value;
                    keepmaskCheckBoxValueChanged(app,event);
                    NextmaskButtonPushed(app,event);
                case 'q'
                    SaveselectionandcloseButtonPushed(app,event);
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.zproj_ax = uiaxes(app.UIFigure);
            title(app.zproj_ax, 'Z projection')
            xlabel(app.zproj_ax, '')
            ylabel(app.zproj_ax, '')
            zlabel(app.zproj_ax, '')
            app.zproj_ax.XTick = [];
            app.zproj_ax.YTick = [];
            app.xproj_ax = uiaxes(app.UIFigure);
            title(app.xproj_ax, 'X projection')
            xlabel(app.xproj_ax, '')
            ylabel(app.xproj_ax, '')
            zlabel(app.xproj_ax, '')
            app.xproj_ax.XTick = [];
            app.xproj_ax.YTick = [];
            app.yproj_ax = uiaxes(app.UIFigure);
            title(app.yproj_ax, 'Y projection')
            xlabel(app.yproj_ax, '')
            ylabel(app.yproj_ax, '')
            zlabel(app.yproj_ax, '')
            app.yproj_ax.XTick = [];
            app.yproj_ax.YTick = [];
            app.yproj_ax.YTickLabel = '';
            app.mask_3d = uiaxes(app.UIFigure);
            title(app.mask_3d, '3D Mask')
            xlabel(app.mask_3d, 'X')
            ylabel(app.mask_3d, 'Y')
            zlabel(app.mask_3d, 'Z')
            app.TraceEstimate = uiaxes(app.UIFigure);
            title(app.TraceEstimate, 'Trace estimate')
            xlabel(app.TraceEstimate, 'X')
            ylabel(app.TraceEstimate, 'Y')
            zlabel(app.TraceEstimate, 'Z')
            app.MasknumberEditFieldLabel = uilabel(app.UIFigure);
            app.MasknumberEditFieldLabel.Text = 'Mask number';
            app.MasknumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.MasknumberEditField.Limits = [1 Inf];
            app.MasknumberEditField.ValueChangedFcn = createCallbackFcn(app, @MasknumberEditFieldValueChanged, true);
            app.MasknumberEditField.Value = 1;
            app.NextmaskButton = uibutton(app.UIFigure, 'push');
            app.NextmaskButton.ButtonPushedFcn = createCallbackFcn(app, @NextmaskButtonPushed, true);
            app.NextmaskButton.Text = 'Next mask';
            app.PreviousmaskButton = uibutton(app.UIFigure, 'push');
            app.PreviousmaskButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousmaskButtonPushed, true);
            app.PreviousmaskButton.Text = 'Previous mask';
            app.keepmaskCheckBox = uicheckbox(app.UIFigure);
            app.keepmaskCheckBox.ValueChangedFcn = createCallbackFcn(app, @keepmaskCheckBoxValueChanged, true);
            app.keepmaskCheckBox.Text = 'keep mask';
            app.SaveselectionandcloseButton = uibutton(app.UIFigure, 'push');
            app.SaveselectionandcloseButton.ButtonPushedFcn = createCallbackFcn(app, @SaveselectionandcloseButtonPushed, true);
            app.SaveselectionandcloseButton.Text = 'Save selection and close';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SegmentationROI3D(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end